classdef FrameAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        FixedValueButtonGroup          matlab.ui.container.ButtonGroup
        FixedMButton                   matlab.ui.control.RadioButton
        FixedNButton                   matlab.ui.control.RadioButton
        FixedGammaButton               matlab.ui.control.RadioButton
        MinValueEditFieldLabel         matlab.ui.control.Label
        MinValueEditField              matlab.ui.control.NumericEditField
        MaxValueEditFieldLabel         matlab.ui.control.Label
        MaxValueEditField              matlab.ui.control.NumericEditField
        NumberofvaluesEditFieldLabel   matlab.ui.control.Label
        NumberofvaluesEditField        matlab.ui.control.NumericEditField
        CreateFramesButton             matlab.ui.control.Button
        ChangingValueButtonGroup       matlab.ui.container.ButtonGroup
        ChangingMButton                matlab.ui.control.RadioButton
        ChangingNButton                matlab.ui.control.RadioButton
        ChangingGammaButton            matlab.ui.control.RadioButton
        FixedValueEditFieldLabel       matlab.ui.control.Label
        FixedValueEditField            matlab.ui.control.NumericEditField
        FrameCodeTypeDropDownLabel     matlab.ui.control.Label
        FrameCodeTypeDropDown          matlab.ui.control.DropDown
        SubtypeDropDownLabel           matlab.ui.control.Label
        SubtypeDropDown                matlab.ui.control.DropDown
        IntervalsinLogarithmicscaleCheckBox  matlab.ui.control.CheckBox
        Tab2                           matlab.ui.container.Tab
        ReadyLampLabel                 matlab.ui.control.Label
        ReadyLamp                      matlab.ui.control.Lamp
        UIAxes                         matlab.ui.control.UIAxes
        TabGroup2                      matlab.ui.container.TabGroup
        AmplificationVsGammTab         matlab.ui.container.Tab
        CalculateAmplificationButton   matlab.ui.control.Button
        AddDistributionButton          matlab.ui.control.Button
        CurrentDistributionDropDownLabel  matlab.ui.control.Label
        CurrentDistributionDropDown    matlab.ui.control.DropDown
        ConditionNumberVsGammaTab      matlab.ui.container.Tab
        CalculateCNButton              matlab.ui.control.Button
        AddDistributionButton_2        matlab.ui.control.Button
        CurrentDistributionDropDown_2Label  matlab.ui.control.Label
        CurrentDistributionDropDown_2  matlab.ui.control.DropDown
        FrameStatisticsTab             matlab.ui.container.Tab
        CalculateAllpossibleNAButton   matlab.ui.control.Button
        CalculateAllpossibleCNButton   matlab.ui.control.Button
        ViewOptionsTab                 matlab.ui.container.Tab
        PlotLocationButtonGroup        matlab.ui.container.ButtonGroup
        GUIButton                      matlab.ui.control.RadioButton
        NewFigureButton                matlab.ui.control.RadioButton
        ClearAxesButton                matlab.ui.control.Button
        CopyAxesButton                 matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        NumTrialsEditFieldLabel        matlab.ui.control.Label
        NumTrialsEditField             matlab.ui.control.NumericEditField
        ChoosenFrameLabel              matlab.ui.control.Label
        ChoosenFrameDropDown           matlab.ui.control.DropDown
        KtoNratioLabel                 matlab.ui.control.Label
        KtoNratioEditField             matlab.ui.control.NumericEditField
        PreformanceMeasureButtonGroup  matlab.ui.container.ButtonGroup
        NoneButton                     matlab.ui.control.RadioButton
        AlphaTruncateButton_2          matlab.ui.control.RadioButton
        LogAverageButton               matlab.ui.control.RadioButton
        EigenvalueOffset1Button        matlab.ui.control.RadioButton
        AlphaEditFieldLabel            matlab.ui.control.Label
        AlphaEditField                 matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function lampPrevState = flipLampSwitch(app)
            lampColor = app.ReadyLamp.Color;
            if isequal(lampColor, [0,1,0]) % was green
                lampPrevState = true;
                app.ReadyLamp.Color = [1,0,0];
                app.ReadyLampLabel.Text = 'Please Wait';
            else
                lampPrevState = false;
            end
        end
        
        function [] = TurnOnLamp(app, lampPrevState)
            if lampPrevState
                app.ReadyLamp.Color = [0,1,0];
                app.ReadyLampLabel.Text = 'Ready';
            end
        end
        
        function [] = updateApp(app)
            global AllFrameParameters
            global AllFramesTable
            AllFramesTable = [];
            
            if ~isempty(AllFrameParameters)
                AllFramesTable = table(1, AllFrameParameters(1).Type,  AllFrameParameters(1).SubType, min(AllFrameParameters(1).N), max(AllFrameParameters(1).N), min(AllFrameParameters(1).M), max(AllFrameParameters(1).M));
                for i = 2:numel(AllFrameParameters)
                    AllFramesTable= [AllFramesTable; ...
                        {i, AllFrameParameters(i).Type, AllFrameParameters(i).SubType, min(AllFrameParameters(i).N), max(AllFrameParameters(i).N), min(AllFrameParameters(i).M), max(AllFrameParameters(i).M)}];
                end
            end
            
            FrameTypeInDropdown = app.FrameCodeTypeDropDown.Value;
            switch FrameTypeInDropdown
                case 'BCH'
                    app.SubtypeDropDown.Items = {'None'};
                case 'LPF'
                    app.SubtypeDropDown.Items = {'None'};
                case 'Wishart'
                    app.SubtypeDropDown.Items = {'None'};
                case 'Reed Solomon'
                    app.SubtypeDropDown.Items = {'None', 'Uniform sample', 'Random Set', 'Non-uniform sample', 'Multiple non-uniform sample'};
                case 'Omitted Vandermonde'
                    app.SubtypeDropDown.Items = {'None'};
                otherwise
                    app.SubtypeDropDown.Items = {'None'};
            end


            app.UITable.Data = AllFramesTable;
            if ~isempty(app.UITable.Data)
%                 app.UITable.Data{:,1}
                app.ChoosenFrameDropDown.Items = cellstr(num2str(app.UITable.Data{:,1}));
            end
        end
        
        
        function figureDisplaySetup(app, ax, P, numTests, analysisType)
            switch analysisType
                case 'Noise Amplification Vs. gamma'
                    titleStr = { 'Noise Amplification Vs. \gamma^{-1}'; ...
                        ['Fixed k/n ratio of ', num2str(P)]; ['Number of tests: ', num2str(numTests)] ...
                        };
                    
                    labelX = '\gamma^{-1}';
                    labelY = 'Noise Amplification [dB]';
                    
                case 'Condition Number'
                    titleStr = { 'Condition Number Vs. \gamma'; ...
                        ['Fixed k/n ratio of ', num2str(P)]; ['Number of tests: ', num2str(numTests)] ...
                        };
                    labelX = '\gamma^{-1}';
                    labelY = 'Condition number';
                    set(ax, 'XScale', 'log');
                case 'Frame Statistics'
                    titleStr = { 'Frame statistics of Noise Amplification Vs. Frame permutation number'; ...
                        ['Fixed k/n ratio of ', num2str(P)]; ['Number of tests: ', num2str(numTests)] ...
                        };

                    labelX = 'Frame permutation number';
                    labelY = 'Noise Amplification [dB]';
                otherwise
                    error('No appropriate graph display settings implemented');
            end
            
            fontsize = 16;
            title(ax, titleStr, 'fontsize', fontsize);
            xlabel(ax, labelX, 'fontsize', fontsize);
            ylabel(ax, labelY, 'fontsize', fontsize);
            grid(ax, 'on');     hold(ax, 'on');
        end
        
        
        function [currFrameParameters, numTests, P] = getUserChoosenFrame(app)
            global AllFrameParameters
            badInputStr = 'Bad user input, see msgbox';
            Item = app.ChoosenFrameDropDown.Items;
            if strcmpi(Item{1}, 'NULL')
                msgbox('No frames loaded in the GUI','Input error','error');
                error(badInputStr);
            else
                iFrames = str2num(app.ChoosenFrameDropDown.Value);
                numTests = app.NumTrialsEditField.Value;
                P = app.KtoNratioEditField.Value/100;
                gammas = AllFrameParameters(iFrames).Gamma;
                if ~all(P >= gammas)
                    msgbox('K to N ratio must be greater or equal than all gamma values','Input error','error');
                    error(badInputStr);
                end
                currFrameParameters = AllFrameParameters(iFrames);
            end
        end
        
        
        function [averagingMethod, alpha] = getUserChoosenAveragingMethod(app)
            averagingMethod = app.PreformanceMeasureButtonGroup.SelectedObject.Text;
            alpha = app.AlphaEditField.Value/100;
        end
       
        function [] = plotData(ax, x, y, displayStr)
            linewidth = 2;
            if numel(x) == 1 || isempty(x)
                new_x = get(ax, 'xlim');
                plot(ax, new_x, y, '--', 'DisplayName', displayStr, 'LineWidth', linewidth);
            elseif numel(x) < 50
                plot(ax, x, y, '-o' ,'DisplayName', displayStr, 'LineWidth', linewidth);
            else
                plot(ax, x, y,'DisplayName', displayStr, 'LineWidth',linewidth);
            end
        end
    
    
    end

    methods (Access = private)

        % Button pushed function: CreateFramesButton
        function CreateFramesButtonPushed(app, event)
            global AllFrameParameters
            %% Turn off lamp
            lampPrevState = flipLampSwitch(app);
            
            %% Initialize needed parameters
            M= [];
            N= [];
            Gamma = [];
            validOptionFlag = true;
            badChoisemsgTitle = 'Bad choise';
            
            %% Get and check values of entered values
            FrameType = app.FrameCodeTypeDropDown.Value;
            SubType = app.SubtypeDropDown.Value;
            
            fixedValue = app.FixedValueEditField.Value;
            if fixedValue <= 0
                msgbox('the fixed value has to be greater than 0', badChoisemsgTitle,'error');
                validOptionFlag = false;
            end
            
            minVal = app.MinValueEditField.Value;
            maxVal = app.MaxValueEditField.Value;
            NVal = app.NumberofvaluesEditField.Value;
            linearInterval = ~app.IntervalsinLogarithmicscaleCheckBox.Value;
            
            if round(NVal) ~= NVal
                msgbox('Number of values can not be 0 or not an integer', badChoisemsgTitle,'error');
                validOptionFlag = false;
            else
                if linearInterval
                    ChangingVal = linspace(minVal, maxVal, NVal);
                else
                    ChangingVal = linspace(log10(minVal), log10(maxVal), NVal);
                    ChangingVal = 10.^ChangingVal;
                end
                
                fixedValue = fixedValue * ones(size(ChangingVal));
            end
            
            %% Switch case of which options where choosen
            FixedValue = app.FixedValueButtonGroup.SelectedObject.Text;
            ChangingValue = app.ChangingValueButtonGroup.SelectedObject.Text;
            
            choiseMsgStr = 'Fixed and changing values are the same';
            if validOptionFlag
                switch FixedValue
                    case 'Fixed M'
                        M = fixedValue;
                        switch ChangingValue
                            case 'Changing M'
                                msgbox(choiseMsgStr, badChoisemsgTitle, 'error');
                                validOptionFlag = false;
                            case 'Changing N'
                                N = ChangingVal;
                                Gamma = M./N;
                            case 'Changing Gamma'
                                Gamma = ChangingVal;
                                M = N * Gamma;
                        end
                        
                        if any(M > N)
                            msgbox('M has to be less or equal all N', badChoisemsgTitle, 'error');
                            validOptionFlag = false;
                        end
                        
                    case 'Fixed N'
                        N = fixedValue;
                        switch ChangingValue
                            case 'Changing M'
                                M = ChangingVal;
                                Gamma = M./N;
                            case 'Changing N'
                                msgbox(choiseMsgStr, badChoisemsgTitle, 'error');
                                validOptionFlag = false;
                            case 'Changing Gamma'
                                Gamma = ChangingVal;
                                N = M./Gamma;
                        end
                        
                    case 'Fixed Gamma'
                        Gamma = fixedValue;
                        switch ChangingValue
                            case 'Changing M'
                                M = ChangingVal;
                                N = M./Gamma;
                                
                            case 'Changing N'
                                N = ChangingVal;
                                M = N .* Gamma;
                                
                            case 'Changing Gamma'
                                msgbox(choiseMsgStr, badChoisemsgTitle, 'error');
                                validOptionFlag = false;
                        end
                        
                        if Gamma > 1
                            msgbox('Gamma has to be less than 1', badChoisemsgTitle, 'error');
                            validOptionFlag = false;
                        end
                        
                    otherwise
                        error('Not implemented');
                end
            end
            
            M = round(M); N = round(N);
            
            normDim = 'Column'; % TODO: change to be a user input
            if validOptionFlag
                newFrameParameters = FrameParameters(FrameType, SubType, M, N, Gamma, normDim);
                AllFrameParameters = [AllFrameParameters, newFrameParameters];
            end
            
            %% Turn on lamp
            TurnOnLamp(app, lampPrevState);
            
            updateApp(app);
        end

        % Button pushed function: CalculateAmplificationButton
        function CalculateAmplificationButtonPushed(app, event)
            lampPrevState = flipLampSwitch(app);
            pause(0.1);
            [currFrameParameters, numTests, P] = getUserChoosenFrame(app);
            graphLocStr = app.PlotLocationButtonGroup.SelectedObject.Text;
            if strcmpi(graphLocStr, 'GUI')
                ax = app.UIAxes;
            else
                hFig = figure(); ax = axes(hFig);
            end
            [averagingMethod, alpha] = getUserChoosenAveragingMethod(app);
            [gammaInv, results] = currFrameParameters.getNoiseAmplificationPerGamma(P, numTests, averagingMethod, alpha);
            %             NoiseAmp_dB = 10*log10(NoiseAmplification);
            
            displayStr = currFrameParameters.Type;
            if ~strcmp(currFrameParameters.SubType, 'None') && ~isempty(currFrameParameters.SubType)
                displayStr = [displayStr, ' - ', currFrameParameters.SubType];
            end
            
            if strcmp(averagingMethod, 'Alpha Truncate')
                displayStr = [displayStr, '. \alpha = ', num2str(alpha*100), '%'];
            end
            
            if strcmp('Condition Number' , averagingMethod)
                figureDisplaySetup(app, ax, P, numTests, 'Condition Number');
                x_axis = currFrameParameters.N;
            else
                figureDisplaySetup(app, ax, P, numTests, 'Noise Amplification Vs. gamma');
                x_axis = gammaInv;
            end
            
            plotData(ax, x_axis, results, displayStr);
            legend(ax);
            updateApp(app);
            TurnOnLamp(app, lampPrevState);
        end

        % Button pushed function: ClearAxesButton
        function ClearAxesButtonPushed(app, event)
            cla(app.UIAxes);
            updateApp(app);
        end

        % Button pushed function: AddDistributionButton
        function AddDistributionButtonPushed(app, event)
            global AllFrameParameters
            if isempty(AllFrameParameters)
                error('No Frames entered yet');
            end
            
            lampPrevState = flipLampSwitch(app);
            ax = app.UIAxes;
            numTests = app.NumTrialsEditField.Value;
            P = app.KtoNratioEditField.Value/100;
            numEigenValues = 1000;
            figureDisplaySetup(app, ax, P, numTests, 'Noise Amplification Vs. gamma');
            
            minGamma = inf; maxGamma = -inf;
            for i = 1:numel(AllFrameParameters)
                currFrame = AllFrameParameters(i);
                maxGamma = max([maxGamma, max(currFrame.Gamma)]);
                minGamma = min([minGamma, min(currFrame.Gamma)]);
            end
            
            X=0.001:0.001:10;
            %             maxGamma = P;%app.MaxGammaInverseEditField.Value;
            gamma_vect= linspace(minGamma,maxGamma, 1000);
            gammaInv_vect = gamma_vect.^-1;
            NoiseAmp_Vect = zeros(size(gamma_vect));
            
            distrib = app.CurrentDistributionDropDown.Value;
            for jGamma = 1:numel(gamma_vect)
                gamma = gamma_vect(jGamma); 
                beta = gamma/P; % m/k
                switch distrib
                    case 'MP (Marchenko Pastor)'
                        [PDF] = marchenkoPasturPDF(X, beta);
                        displayStr = 'Marchenko Pastor';
                    case 'MANOVA'
                        [PDF] = manovaPDF(X, beta, gamma);
                        displayStr = 'MANOVA';
                end
                
                CDF = cumsum(PDF);
                CDF = CDF/max(CDF);
                R = rand(numEigenValues,1);
                EigenIdx = zeros(1,numel(R));
                for i=1:numel(R)
                    temp = find(R(i)<CDF,1,'first');
                    if isempty(temp)
                        error('what happend?');
                    end
                    EigenIdx(i)= temp;
                end
                Eigen = X(EigenIdx);
                NoiseAmp_Vect(jGamma) = sum(1./Eigen)*sum(Eigen)/(numEigenValues^2);
            end
            
            NoiseAmp_dB = 10*log10(NoiseAmp_Vect);
            
            plot(ax, (gammaInv_vect), NoiseAmp_dB, 'DisplayName', displayStr);
            legend(ax);
            
            updateApp(app);
            TurnOnLamp(app, lampPrevState);
        end

        % Button pushed function: CopyAxesButton
        function CopyAxesButtonPushed(app, event)
            GUIax = app.UIAxes;
            hFig = figure(); ax = axes(hFig);
            copyobj(GUIax.Children,ax);
            labelX = GUIax.XLabel.String;
            labelY = GUIax.YLabel.String;
            titleStr = GUIax.Title.String;
            xlabel(ax, labelX, 'fontsize',16);  ylabel(ax, labelY, 'fontsize',16);
            title(ax, titleStr);
            grid(ax, 'on'); legend(ax);
            plotbrowser('on');
        end

        % Button pushed function: CalculateAllpossibleNAButton
        function CalculateAllpossibleNAButtonPushed(app, event)
            lampPrevState = flipLampSwitch(app);
            pause(0.1);
            [currFrameParameters, numTests, P] = getUserChoosenFrame(app);
            graphLocStr = app.PlotLocationButtonGroup.SelectedObject.Text;
            if strcmpi(graphLocStr, 'GUI')
                ax = app.UIAxes;
            else
                hFig = figure(); ax = axes(hFig);
            end
            figureDisplaySetup(app, ax, P, numTests, 'Frame Statistics');
            [averagingMethod, alpha] = getUserChoosenAveragingMethod(app);
            
            [res] = currFrameParameters.gatherStatisticsFrameByFrame(P, numTests, averagingMethod , alpha, []);
            for i = 1:numel(res)
                tempRes = res{i};
                displayStr = currFrameParameters.Type;
                if ~strcmp(currFrameParameters.SubType, 'None') && ~isempty(currFrameParameters.SubType)
                    displayStr = [displayStr, ' - ', currFrameParameters.SubType];
                end
                
                displayStr = [displayStr, ': N = ', num2str(currFrameParameters.N(i)), ' M = ', num2str(currFrameParameters.M(i))];
                
                if numel(tempRes) == 1
                    x=get(ax, 'xlim');
                    plot(ax, x, [tempRes tempRes], '--', 'DisplayName', displayStr);
%                     hline(ax, tempRes, '--', displayStr); %yline();
                else
                    plot(ax, 1:numel(tempRes), tempRes, '-o' ,'DisplayName', displayStr);
                end
            end
            legend(ax);
        end

        % Button pushed function: CalculateAllpossibleCNButton
        function CalculateAllpossibleCNButtonPushed(app, event)
            lampPrevState = flipLampSwitch(app);
            pause(0.1);
            [currFrameParameters, numTests, P] = getUserChoosenFrame(app);
            graphLocStr = app.PlotLocationButtonGroup.SelectedObject.Text;
            if strcmpi(graphLocStr, 'GUI')
                ax = app.UIAxes;
            else
                hFig = figure(); ax = axes(hFig);
            end
            figureDisplaySetup(app, ax, P, numTests, 'Frame Statistics');
            [averagingMethod, alpha] = getUserChoosenAveragingMethod(app);
            [res] = currFrameParameters.gatherStatisticsfromAllFrames(P, numTests, 'Condition Number' , alpha, []);
            for i = 1:numel(res)
                tempRes = res{i};
                displayStr = [currFrameParameters.Type, ': N = ', num2str(currFrameParameters.N(i)), ' M = ', num2str(currFrameParameters.M(i))];
                plot(ax, 1:numel(tempRes), tempRes, '-o' ,'DisplayName', displayStr);
            end
            legend(ax);
        end

        % Value changed function: FrameCodeTypeDropDown
        function FrameCodeTypeDropDownValueChanged(app, event)
            updateApp(app);
        end

        % Button pushed function: CalculateCNButton
        function CalculateCNButtonPushed(app, event)
            lampPrevState = flipLampSwitch(app);
            pause(0.1);
            [currFrameParameters, numTests, P] = getUserChoosenFrame(app);
            graphLocStr = app.PlotLocationButtonGroup.SelectedObject.Text;
            if strcmpi(graphLocStr, 'GUI')
                ax = app.UIAxes;
            else
                hFig = figure(); ax = axes(hFig);
            end
            figureDisplaySetup(app, ax, P, numTests, 'Condition Number');
            
            displayStr = currFrameParameters.Type;
            if ~strcmp(currFrameParameters.SubType, 'None') && ~isempty(currFrameParameters.SubType)
                displayStr = [displayStr, ' - ', currFrameParameters.SubType];
            end
            
            [gammaInv, ConditionNumbers] = currFrameParameters.getNoiseAmplificationPerGamma(obj, P, numTests, 'Condition Number' , []);

                   
            plot(ax, gammaInv, ConditionNumbers, '-o' ,'DisplayName', displayStr);  legend(ax);
            updateApp(app);
            TurnOnLamp(app, lampPrevState);
        end

        % Button pushed function: AddDistributionButton_2
        function AddDistributionButton_2Pushed(app, event)
            global AllFrameParameters
            if isempty(AllFrameParameters)
                error('No Frames entered yet');
            end
            
            lampPrevState = flipLampSwitch(app);
            ax = app.UIAxes;
            numTests = app.NumTrialsEditField.Value;
            P = app.KtoNratioEditField.Value/100;
            numEigenValues = 1000;
            figureDisplaySetup(app, ax, P, numTests, 'Condition Number');
            
            minGamma = inf; maxGamma = -inf;
            for i = 1:numel(AllFrameParameters)
                currFrame = AllFrameParameters(i);
                maxGamma = max([maxGamma, max(currFrame.Gamma)]);
                minGamma = min([minGamma, min(currFrame.Gamma)]);
            end
            
            X=0.001:0.001:10;
            %             maxGamma = P;%app.MaxGammaInverseEditField.Value;
            gamma_vect= linspace(minGamma,maxGamma, 1000);
            gammaInv_vect = gamma_vect.^-1;
            ConditionNumber = zeros(size(gamma_vect));
            distrib = app.CurrentDistributionDropDown_2.Value;
            for jGamma = 1:numel(gamma_vect)
                gamma = gamma_vect(jGamma); 
                beta = gamma/P;
                switch distrib
                    case 'MP (Marchenko Pastor)'
                        displayStr = 'Marchenko Pastor';
                        LambdaPlus = (1+sqrt(beta))^2;   LambdaMinus = (1-sqrt(beta))^2;
                        ConditionNumber(jGamma) = LambdaPlus / LambdaMinus;
                        
                    case 'MANOVA'
                        displayStr = 'MANOVA';
                        
                        leftTerm = sqrt((1-gamma)/beta);
                        rightTerm = sqrt(1-gamma/beta);
                        rPlus = (leftTerm + rightTerm)^2;   rMinus = (leftTerm - rightTerm)^2;
                        denom = gamma * ( 1 + 1/beta ) - 1;
                        if denom > 0 && (1/gamma) > rPlus
                            rPlus = 1/gamma;
                        end
                        
                        ConditionNumber(jGamma) = rPlus / rMinus;
                        
                end
            end

            plot(ax, (gammaInv_vect), ConditionNumber, 'DisplayName', displayStr);
            legend(ax);
            updateApp(app);
            TurnOnLamp(app, lampPrevState);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.FixedValueButtonGroup = uibuttongroup(app.Tab);
            app.FixedValueButtonGroup.Title = 'Fixed Value';
            app.FixedMButton = uiradiobutton(app.FixedValueButtonGroup);
            app.FixedMButton.Text = 'Fixed M';
            app.FixedMButton.Value = true;
            app.FixedNButton = uiradiobutton(app.FixedValueButtonGroup);
            app.FixedNButton.Text = 'Fixed N';
            app.FixedGammaButton = uiradiobutton(app.FixedValueButtonGroup);
            app.FixedGammaButton.Text = 'Fixed Gamma';
            app.MinValueEditFieldLabel = uilabel(app.Tab);
            app.MinValueEditFieldLabel.Text = 'Min Value';
            app.MinValueEditField = uieditfield(app.Tab, 'numeric');
            app.MinValueEditField.Limits = [1 Inf];
            app.MinValueEditField.Value = 1;
            app.MaxValueEditFieldLabel = uilabel(app.Tab);
            app.MaxValueEditFieldLabel.Text = 'Max Value';
            app.MaxValueEditField = uieditfield(app.Tab, 'numeric');
            app.MaxValueEditField.Limits = [1 Inf];
            app.MaxValueEditField.Value = 1;
            app.NumberofvaluesEditFieldLabel = uilabel(app.Tab);
            app.NumberofvaluesEditFieldLabel.Text = 'Number of values';
            app.NumberofvaluesEditField = uieditfield(app.Tab, 'numeric');
            app.NumberofvaluesEditField.Limits = [1 Inf];
            app.NumberofvaluesEditField.Value = 1;
            app.CreateFramesButton = uibutton(app.Tab, 'push');
            app.CreateFramesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateFramesButtonPushed, true);
            app.CreateFramesButton.Text = 'Create Frames';
            app.ChangingValueButtonGroup = uibuttongroup(app.Tab);
            app.ChangingValueButtonGroup.Title = 'Changing Value';
            app.ChangingMButton = uiradiobutton(app.ChangingValueButtonGroup);
            app.ChangingMButton.Text = 'Changing M';
            app.ChangingNButton = uiradiobutton(app.ChangingValueButtonGroup);
            app.ChangingNButton.Text = 'Changing N';
            app.ChangingNButton.Value = true;
            app.ChangingGammaButton = uiradiobutton(app.ChangingValueButtonGroup);
            app.ChangingGammaButton.Text = 'Changing Gamma';
            app.FixedValueEditFieldLabel = uilabel(app.Tab);
            app.FixedValueEditFieldLabel.Text = 'Fixed Value';
            app.FixedValueEditField = uieditfield(app.Tab, 'numeric');
            app.FixedValueEditField.Limits = [0 Inf];
            app.FrameCodeTypeDropDownLabel = uilabel(app.Tab);
            app.FrameCodeTypeDropDownLabel.Text = 'Frame/Code Type';
            app.FrameCodeTypeDropDown = uidropdown(app.Tab);
            app.FrameCodeTypeDropDown.Items = {'BCH', 'LPF', 'Wishart', 'Reed Solomon', 'Omitted Vandermonde'};
            app.FrameCodeTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FrameCodeTypeDropDownValueChanged, true);
            app.FrameCodeTypeDropDown.Value = 'BCH';
            app.SubtypeDropDownLabel = uilabel(app.Tab);
            app.SubtypeDropDownLabel.Text = 'Sub-type';
            app.SubtypeDropDown = uidropdown(app.Tab);
            app.SubtypeDropDown.Items = {'None', 'Uniform sample', 'Random set', 'Non-uniform sample', 'Multiple non-uniform sample'};
            app.SubtypeDropDown.Value = 'None';
            app.IntervalsinLogarithmicscaleCheckBox = uicheckbox(app.Tab);
            app.IntervalsinLogarithmicscaleCheckBox.Text = 'Intervals in Logarithmic scale ';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.AmplificationVsGammTab = uitab(app.TabGroup2);
            app.AmplificationVsGammTab.Title = 'Amplification Vs. Gamm';
            app.CalculateAmplificationButton = uibutton(app.AmplificationVsGammTab, 'push');
            app.CalculateAmplificationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAmplificationButtonPushed, true);
            app.CalculateAmplificationButton.Text = 'Calculate Amplification';
            app.AddDistributionButton = uibutton(app.AmplificationVsGammTab, 'push');
            app.AddDistributionButton.ButtonPushedFcn = createCallbackFcn(app, @AddDistributionButtonPushed, true);
            app.AddDistributionButton.Text = 'Add Distribution ';
            app.CurrentDistributionDropDownLabel = uilabel(app.AmplificationVsGammTab);
            app.CurrentDistributionDropDownLabel.Text = 'Current Distribution';
            app.CurrentDistributionDropDown = uidropdown(app.AmplificationVsGammTab);
            app.CurrentDistributionDropDown.Items = {'MANOVA', 'MP (Marchenko Pastor)'};
            app.CurrentDistributionDropDown.Value = 'MANOVA';
            app.ConditionNumberVsGammaTab = uitab(app.TabGroup2);
            app.ConditionNumberVsGammaTab.Title = 'Condition Number Vs. Gamma';
            app.CalculateCNButton = uibutton(app.ConditionNumberVsGammaTab, 'push');
            app.CalculateCNButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateCNButtonPushed, true);
            app.CalculateCNButton.Text = 'Calculate C.N';
            app.AddDistributionButton_2 = uibutton(app.ConditionNumberVsGammaTab, 'push');
            app.AddDistributionButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddDistributionButton_2Pushed, true);
            app.AddDistributionButton_2.Text = 'Add Distribution ';
            app.CurrentDistributionDropDown_2Label = uilabel(app.ConditionNumberVsGammaTab);
            app.CurrentDistributionDropDown_2Label.Text = 'Current Distribution';
            app.CurrentDistributionDropDown_2 = uidropdown(app.ConditionNumberVsGammaTab);
            app.CurrentDistributionDropDown_2.Items = {'MANOVA', 'MP (Marchenko Pastor)'};
            app.CurrentDistributionDropDown_2.Value = 'MANOVA';
            app.FrameStatisticsTab = uitab(app.TabGroup2);
            app.FrameStatisticsTab.Title = 'Frame Statistics';
            app.CalculateAllpossibleNAButton = uibutton(app.FrameStatisticsTab, 'push');
            app.CalculateAllpossibleNAButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAllpossibleNAButtonPushed, true);
            app.CalculateAllpossibleNAButton.Text = 'Calculate All possible N.A';
            app.CalculateAllpossibleCNButton = uibutton(app.FrameStatisticsTab, 'push');
            app.CalculateAllpossibleCNButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAllpossibleCNButtonPushed, true);
            app.CalculateAllpossibleCNButton.Text = 'Calculate All possible C.N';
            app.ViewOptionsTab = uitab(app.TabGroup2);
            app.ViewOptionsTab.Title = 'View Options';
            app.PlotLocationButtonGroup = uibuttongroup(app.ViewOptionsTab);
            app.PlotLocationButtonGroup.Title = 'Plot Location';
            app.GUIButton = uiradiobutton(app.PlotLocationButtonGroup);
            app.GUIButton.Text = 'GUI';
            app.GUIButton.Value = true;
            app.NewFigureButton = uiradiobutton(app.PlotLocationButtonGroup);
            app.NewFigureButton.Text = 'New Figure';
            app.ClearAxesButton = uibutton(app.ViewOptionsTab, 'push');
            app.ClearAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAxesButtonPushed, true);
            app.ClearAxesButton.Text = 'Clear Axes';
            app.CopyAxesButton = uibutton(app.ViewOptionsTab, 'push');
            app.CopyAxesButton.ButtonPushedFcn = createCallbackFcn(app, @CopyAxesButtonPushed, true);
            app.CopyAxesButton.Text = 'Copy Axes';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'#'; 'Frame type'; 'Sub-type'; 'min N'; 'max N'; 'min M'; 'Max M'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false false false false false true];
            app.NumTrialsEditFieldLabel = uilabel(app.UIFigure);
            app.NumTrialsEditFieldLabel.Text = 'Num Trials:';
            app.NumTrialsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumTrialsEditField.Limits = [1 Inf];
            app.NumTrialsEditField.Value = 1000;
            app.ChoosenFrameLabel = uilabel(app.UIFigure);
            app.ChoosenFrameLabel.Text = 'Choosen Frame:';
            app.ChoosenFrameDropDown = uidropdown(app.UIFigure);
            app.ChoosenFrameDropDown.Items = {'NULL'};
            app.ChoosenFrameDropDown.Value = 'NULL';
            app.KtoNratioLabel = uilabel(app.UIFigure);
            app.KtoNratioLabel.Text = 'K to N ratio (%)';
            app.KtoNratioEditField = uieditfield(app.UIFigure, 'numeric');
            app.KtoNratioEditField.Limits = [1 Inf];
            app.KtoNratioEditField.Value = 100;
            app.PreformanceMeasureButtonGroup = uibuttongroup(app.UIFigure);
            app.PreformanceMeasureButtonGroup.Title = 'Preformance Measure';
            app.NoneButton = uiradiobutton(app.PreformanceMeasureButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.AlphaTruncateButton_2 = uiradiobutton(app.PreformanceMeasureButtonGroup);
            app.AlphaTruncateButton_2.Text = 'Alpha Truncate';
            app.LogAverageButton = uiradiobutton(app.PreformanceMeasureButtonGroup);
            app.LogAverageButton.Text = 'Log Average';
            app.EigenvalueOffset1Button = uiradiobutton(app.PreformanceMeasureButtonGroup);
            app.EigenvalueOffset1Button.Text = 'Eigenvalue Offset +1';
            app.AlphaEditFieldLabel = uilabel(app.PreformanceMeasureButtonGroup);
            app.AlphaEditFieldLabel.Text = 'Alpha (%)';
            app.AlphaEditField = uieditfield(app.PreformanceMeasureButtonGroup, 'numeric');
            app.AlphaEditField.Limits = [0 Inf];
            app.AlphaEditField.Value = 0.1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = FrameAnalyzer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end